classdef ui_holo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        App_uifigure                   matlab.ui.Figure
        COPYALLBBOXESPanel             matlab.ui.container.Panel
        arrow_left_add_bboxes          matlab.ui.control.Button
        arrow_right_add_bboxes         matlab.ui.control.Button
        deleteallbboxesinvideoButton   matlab.ui.control.Button
        deleteallbboxesinframeButton   matlab.ui.control.Button
        NovideofoundLabel              matlab.ui.control.Label
        suggestionresultTextArea       matlab.ui.control.TextArea
        suggestionresultTextAreaLabel  matlab.ui.control.Label
        SuggestBBoxButton              matlab.ui.control.Button
        showminmaxnormframeCheckBox    matlab.ui.control.CheckBox
        videoplayerPanel               matlab.ui.container.Panel
        framerateEditField             matlab.ui.control.NumericEditField
        framerateEditFieldLabel        matlab.ui.control.Label
        stop_button                    matlab.ui.control.Button
        play_button                    matlab.ui.control.Button
        selectframeSpinner             matlab.ui.control.Spinner
        selectframeSpinnerLabel        matlab.ui.control.Label
        newlabel_button                matlab.ui.control.StateButton
        arrow_right                    matlab.ui.control.Button
        arrow_left                     matlab.ui.control.Button
        hologramvideosListBox          matlab.ui.control.ListBox
        hologramvideosListBoxLabel     matlab.ui.control.Label
        selectvideofolderButton        matlab.ui.control.Button
        ax_holofr                      matlab.ui.control.UIAxes
    end

    properties (Access=private)
        app_path
        vid_folders_struct
        curr_vid_obj
        holo_vids_holder_obj
        holo_vids_holder_array
        current_vid_files_path
        just_past_fr
        stop_now
        pause_btwn_fr
        ctrl_pressed
        copy_bboxes_to_next_fr
        copy_bboxes_to_prev_fr
    end

    methods (Access = private)
        function get_app_path(app)
            app_path_dummy = mfilename('fullpath');

            slash = '\\';
            if isunix()
                slash = '/';
            end

            app.app_path = regexprep(app_path_dummy, [slash 'ui_holo.*'],'');

            cd(app.app_path);
        end
        function init_path_is_set = get_init_vid_files_path(app)
            f_names = [];
            app.current_vid_files_path = '';

            if exist('_videos','dir') == 7
                dir_data = dir('_videos');
                f_names = {dir_data(:).name};
                idx = find(contains(f_names, '.avi'));
                if ~isempty(idx)
                    f_names = f_names(idx);
                    app.current_vid_files_path = '_videos';
                end
            end

            if isempty(app.current_vid_files_path)
                if exist('curr_vids_path.mat','file') == 2
                    load('curr_vids_path.mat');
                    app.current_vid_files_path = curr_vids_path;
                end
            end
            vids = dir(app.current_vid_files_path);
            vids = {vids(:).name};
            idx = find(contains(vids, '.avi'),1);
            init_path_is_set = ~isempty(idx);
        end

        function update_last_fr_no(app)
            app.just_past_fr = fr;
        end

        function update_video_list_box(app)
            vid_file_dir = dir(app.current_vid_files_path);
            vid_names = {vid_file_dir(:).name};
            idx = find(contains(vid_names,'.avi'));
            if isempty(idx)
                app.hologramvideosListBox.Items = {};
                app.current_vid_files_path = '';
                warndlg('No videos found.');
            else
                app.hologramvideosListBox.Items = vid_names(idx);
                app.hologramvideosListBox.Value  = app.hologramvideosListBox.Items{1};
                app.hologramvideosListBoxValueChanged(true);
            end
            app.update_folders_seen_struct();
        end

        function update_folders_seen_struct(app)
            dummy_vid_folders_struct.files_path = app.current_vid_files_path;
            dummy_vid_folders_struct.file_names = app.hologramvideosListBox.Items;

            if isempty(app.vid_folders_struct)
                app.vid_folders_struct = dummy_vid_folders_struct;
            else
                app.vid_folders_struct(end+1) = dummy_vid_folders_struct;
            end
        end

        function ret = video_has_been_loaded(app)
            ret = ~isempty(app.curr_vid_obj) && isvalid(app.curr_vid_obj) ...
                && isa(app.curr_vid_obj,'HoloVid');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            % app.consolidatebboxesButton.Visible = 'off';

            app.get_app_path();
            add_app_folders_to_path();

            app.ax_holofr.Visible = 'on';
            app.ax_holofr.Colormap = gray(256);
            axis(app.ax_holofr, 'image');
            app.framerateEditField.Value = 5;
            app.stop_now = false;

            app.pause_btwn_fr = 1/app.framerateEditField.Value;
            app.selectframeSpinner.Step = 1;
            app.curr_vid_obj = [];
            app.copy_bboxes_to_next_fr = false;
            app.copy_bboxes_to_prev_fr = false;
            app.ctrl_pressed = false;
            init_path_is_set = app.get_init_vid_files_path();

            if init_path_is_set
                app.holo_vids_holder_obj = HoloVidsHolder(app.app_path, app.ax_holofr, app.current_vid_files_path);
                app.update_video_list_box();
            else
                app.holo_vids_holder_obj = HoloVidsHolder(app.app_path, app.ax_holofr);
            end
        end

        % Button pushed function: selectvideofolderButton
        function selectvideofolderButtonPushed(app, event)
            app.current_vid_files_path = uigetdir();

            if isnumeric(app.current_vid_files_path) || isempty(app.current_vid_files_path) %not zero nor empty
                app.current_vid_files_path = '';
            else
                if app.holo_vids_holder_obj.vid_files_path_is_NOT_set()
                    app.holo_vids_holder_obj.set_vid_files_path(app.current_vid_files_path);
                else
                    if app.video_has_been_loaded() %chaging folders, storing objects related to last seen videos so it can be loaded next if needed
                        cd(app.app_path);
                        app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                        app.holo_vids_holder_obj.save_labels();
                    end
                    if isempty(app.holo_vids_holder_array) %adds to the array
                        app.holo_vids_holder_array = app.holo_vids_holder_obj.copy;
                    else
                        app.holo_vids_holder_array(end+1) = app.holo_vids_holder_obj.copy;
                    end
                    tic
                    app.holo_vids_holder_obj = HoloVidsHolder(app.app_path, app.ax_holofr, app.current_vid_files_path); %creates new obj
                    toc
                end


                addpath(app.current_vid_files_path);
                app.update_video_list_box();
            end
        end

        % Value changed function: hologramvideosListBox
        function hologramvideosListBoxValueChanged(app, event)

            if app.video_has_been_loaded() %store remaning labels from previous video
                app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                %STORE VIDEO OBJ INTO MAP HERE
            end
            vid_name_no_ext = app.hologramvideosListBox.Value;
            vid_name_no_ext = vid_name_no_ext(1:end-4);

            if app.holo_vids_holder_obj.vid_is_in_map(vid_name_no_ext)
                app.curr_vid_obj = app.holo_vids_holder_obj.get_vid_obj_from_map(vid_name_no_ext);
            else
                file_path = [app.current_vid_files_path '\' app.hologramvideosListBox.Value];
                if isunix()
                    file_path = regexprep(file_path,'\\','/');
                end
                app.curr_vid_obj = HoloVid(file_path, app.ax_holofr);
            end
            app.framerateEditField.Value = app.curr_vid_obj.get_fr_rate();
            app.framerateEditFieldValueChanged(true);
            app.selectframeSpinner.Limits(2) = app.curr_vid_obj.no_of_frames;
            app.selectframeSpinner.Value = app.curr_vid_obj.selected_fr_no;
            app.showminmaxnormframeCheckBoxValueChanged(true);
            app.selectframeSpinnerValueChanged(true);
        end

        % Button pushed function: arrow_left
        function arrow_leftButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.prev_fr(app.copy_bboxes_to_prev_fr);
                app.copy_bboxes_to_prev_fr = false;
                app.selectframeSpinner.Value = app.curr_vid_obj.selected_fr_no;
                app.selectframeSpinnerValueChanged(true);
            end
        end

        % Button pushed function: arrow_right
        function arrow_rightButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.next_fr(app.copy_bboxes_to_next_fr);
                app.copy_bboxes_to_next_fr = false;

                app.selectframeSpinner.Value = app.curr_vid_obj.selected_fr_no;
                app.selectframeSpinnerValueChanged(true);
            end
        end

        % Key press function: App_uifigure
        function App_uifigureKeyPress(app, event)
            if isa(event,'matlab.ui.eventdata.KeyData')
                key = event.Key;
                if app.ctrl_pressed
                    if strcmpi(key, 'V')
                        app.curr_vid_obj.paste_selected_bbox();
                        app.ctrl_pressed = false;
                    elseif strcmpi(key, 'Z')
                        app.curr_vid_obj.reload_last_deleted_bbox();
                    end
                    app.ctrl_pressed = false;
                else
                    if strcmpi(key, 'delete')
                        app.curr_vid_obj.delete_selected_bbox();
                    elseif strcmpi(key, 'n')
                        app.newlabel_buttonValueChanged(true);
                    elseif strcmpi(key,'rightarrow')
                        app.arrow_rightButtonPushed(true)
                    elseif strcmpi(key, 'leftarrow')
                        app.arrow_leftButtonPushed(true)
                    elseif strcmpi(key, 'C')
                        app.curr_vid_obj.paste_selected_bbox();
                    elseif strcmpi(key, 'control')
                        app.ctrl_pressed = true;
                    end
                end
            else
                evname = event.EventName;
                switch(evname)
                    case{'MovingROI'}
                        app.curr_vid_obj.change_bbox_color();
                end
            end
            pause(0.2);
        end

        % Button pushed function: play_button
        function play_buttonButtonPushed(app, event)
            if app.video_has_been_loaded()
                while ~app.stop_now
                    app.arrow_rightButtonPushed(true);
                    pause(app.pause_btwn_fr);
                end
                app.stop_now = false;
            end
        end

        % Value changed function: framerateEditField
        function framerateEditFieldValueChanged(app, event)
            value = app.framerateEditField.Value;
            if value <= 0
                app.framerateEditField.Value = 1;
            elseif value >= 30
                app.framerateEditField.Value = 30;
            end
            app.pause_btwn_fr = 1/app.framerateEditField.Value;
        end

        % Button pushed function: stop_button
        function stop_buttonButtonPushed(app, event)
            app.stop_now = true;
        end

        % Value changed function: newlabel_button
        function newlabel_buttonValueChanged(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.add_bbox_to_selected_fr();
            end
            app.newlabel_button.Value = false;
        end

        % Button down function: ax_holofr
        function ax_holofrButtonDown(app, event)
            app.App_uifigureKeyPress(event);
        end

        % Value changed function: selectframeSpinner
        function selectframeSpinnerValueChanged(app, event)
            if app.video_has_been_loaded()
                fr = app.selectframeSpinner.Value;
                app.curr_vid_obj.select_fr(fr);
                txt_under_ax = {[' ' app.curr_vid_obj.vid_name]; [' frame ' num2str(app.curr_vid_obj.selected_fr_no)]};
                app.NovideofoundLabel.Text = txt_under_ax;
            end
        end

        % Value changed function: showminmaxnormframeCheckBox
        function showminmaxnormframeCheckBoxValueChanged(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.set_show_I_minmax(app.showminmaxnormframeCheckBox.Value);
            end
        end

        % Callback function: SuggestBBoxButton, 
        % suggestionresultTextArea
        function suggestbboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                found_in_minmax_norm = false;
                [bboxes, scores] = suggest_bboxes(app.curr_vid_obj.get_I_orig());
                if isempty(scores)
                    [bboxes, scores] = suggest_bboxes(app.curr_vid_obj.get_I_min_max_norm());
                    if ~isempty(found_in_minmax_norm)
                        found_in_minmax_norm = true;
                    end
                end
                txt_minmax_norm = '';
                if found_in_minmax_norm
                    txt_minmax_norm = ' using min-max normalized frame image.';
                end
                txt_display_bboxes = {' suggested for ', ['frame ' num2str(app.curr_vid_obj.selected_fr_no)], txt_minmax_norm};
                if isempty(scores)
                    app.suggestionresultTextArea.Value = [{'No bounding boxes'} txt_display_bboxes];
                    app.suggestionresultTextArea.FontColor = 'r';
                    app.suggestionresultTextArea.FontWeight =  'normal';
                else
                    app.suggestionresultTextArea.Value = [{[num2str(length(scores)) ' bounding boxes']} txt_display_bboxes];
                    app.suggestionresultTextArea.FontColor = 'k';
                    app.suggestionresultTextArea.FontWeight = 'bold';
                    for i=1:length(scores)
                        bbox = drawrectangle(app.ax_holofr, 'Position', bboxes(i,:), 'StripeColor','r');
                        app.curr_vid_obj.add_bbox_to_selected_fr(bbox);
                    end
                end
            end
        end

        % Close request function: App_uifigure
        function App_uifigureCloseRequest(app, event)
            if app.video_has_been_loaded()
                cd(app.app_path);

                for i=1:length(app.holo_vids_holder_array)
                    hol_vid_hold_obj = app.holo_vids_holder_array(i);
                    hol_vid_hold_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                    hol_vid_hold_obj.save_labels();
                end
                %                 app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                %                 app.holo_vids_holder_obj.save_labels();

                curr_vids_path = app.current_vid_files_path;
                file_path_n_name = 'app_data\curr_vids_path.mat';

                if exist('app_data','dir') ~= 7
                    mkdir('app_data');
                end

                if isunix()
                    file_path_n_name = regexprep(file_path_n_name,'\\','/');
                end

                save(file_path_n_name,'curr_vids_path');
            end
            delete(app)
        end

        % Button pushed function: arrow_right_add_bboxes
        function arrow_right_add_bboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.copy_bboxes_to_next_fr = true;
                app.arrow_rightButtonPushed(true);
            end
        end

        % Value changing function: selectframeSpinner
        function selectframeSpinnerValueChanging(app, event)
            changingValue = event.Value;

        end

        % Button pushed function: deleteallbboxesinframeButton
        function deleteallbboxesinframeButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.delete_all_bboxes_in_selected_fr();
            end
        end

        % Button pushed function: deleteallbboxesinvideoButton
        function deleteallbboxesinvideoButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.delete_all_bboxes_in_video();
            end
        end

        % Button pushed function: arrow_left_add_bboxes
        function arrow_left_add_bboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.copy_bboxes_to_prev_fr = true;
                app.arrow_leftButtonPushed(true);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.App_uifigure = uifigure('Visible', 'off');
            app.App_uifigure.Name = 'Holo Video Labeler';
            app.App_uifigure.CloseRequestFcn = createCallbackFcn(app, @App_uifigureCloseRequest, true);
            app.App_uifigure.KeyPressFcn = createCallbackFcn(app, @App_uifigureKeyPress, true);
            app.ax_holofr = uiaxes(app.App_uifigure);
            app.ax_holofr.XTick = [];
            app.ax_holofr.XTickLabel = {'[ ]'};
            app.ax_holofr.YTick = [];
            app.ax_holofr.ButtonDownFcn = createCallbackFcn(app, @ax_holofrButtonDown, true);
            app.selectvideofolderButton = uibutton(app.App_uifigure, 'push');
            app.selectvideofolderButton.ButtonPushedFcn = createCallbackFcn(app, @selectvideofolderButtonPushed, true);
            app.selectvideofolderButton.Text = 'select video folder';
            app.hologramvideosListBoxLabel = uilabel(app.App_uifigure);
            app.hologramvideosListBoxLabel.Text = 'hologram videos';
            app.hologramvideosListBox = uilistbox(app.App_uifigure);
            app.hologramvideosListBox.Items = {'', ''};
            app.hologramvideosListBox.ValueChangedFcn = createCallbackFcn(app, @hologramvideosListBoxValueChanged, true);
            app.hologramvideosListBox.Value = '';
            app.arrow_left = uibutton(app.App_uifigure, 'push');
            app.arrow_left.ButtonPushedFcn = createCallbackFcn(app, @arrow_leftButtonPushed, true);
            app.arrow_left.Text = '<';
            app.arrow_right = uibutton(app.App_uifigure, 'push');
            app.arrow_right.ButtonPushedFcn = createCallbackFcn(app, @arrow_rightButtonPushed, true);
            app.arrow_right.Text = '>';
            app.newlabel_button = uibutton(app.App_uifigure, 'state');
            app.newlabel_button.ValueChangedFcn = createCallbackFcn(app, @newlabel_buttonValueChanged, true);
            app.newlabel_button.Text = {'New BBox'; '(press N on '; 'the keyboard)'};
            app.selectframeSpinnerLabel = uilabel(app.App_uifigure);
            app.selectframeSpinnerLabel.Text = 'select frame';
            app.selectframeSpinner = uispinner(app.App_uifigure);
            app.selectframeSpinner.ValueChangingFcn = createCallbackFcn(app, @selectframeSpinnerValueChanging, true);
            app.selectframeSpinner.Limits = [1 Inf];
            app.selectframeSpinner.ValueChangedFcn = createCallbackFcn(app, @selectframeSpinnerValueChanged, true);
            app.selectframeSpinner.Value = 1;
            app.videoplayerPanel = uipanel(app.App_uifigure);
            app.videoplayerPanel.Title = 'video player';
            app.play_button = uibutton(app.videoplayerPanel, 'push');
            app.play_button.ButtonPushedFcn = createCallbackFcn(app, @play_buttonButtonPushed, true);
            app.play_button.Text = '▶';
            app.stop_button = uibutton(app.videoplayerPanel, 'push');
            app.stop_button.ButtonPushedFcn = createCallbackFcn(app, @stop_buttonButtonPushed, true);
            app.stop_button.Text = '▇';
            app.framerateEditFieldLabel = uilabel(app.videoplayerPanel);
            app.framerateEditFieldLabel.Text = 'framerate';
            app.framerateEditField = uieditfield(app.videoplayerPanel, 'numeric');
            app.framerateEditField.ValueChangedFcn = createCallbackFcn(app, @framerateEditFieldValueChanged, true);
            app.showminmaxnormframeCheckBox = uicheckbox(app.App_uifigure);
            app.showminmaxnormframeCheckBox.ValueChangedFcn = createCallbackFcn(app, @showminmaxnormframeCheckBoxValueChanged, true);
            app.showminmaxnormframeCheckBox.Text = 'show min-max norm frame';
            app.SuggestBBoxButton = uibutton(app.App_uifigure, 'push');
            app.SuggestBBoxButton.ButtonPushedFcn = createCallbackFcn(app, @suggestbboxesButtonPushed, true);
            app.SuggestBBoxButton.Text = 'Suggest BBox';
            app.suggestionresultTextAreaLabel = uilabel(app.App_uifigure);
            app.suggestionresultTextAreaLabel.Text = 'suggestion result';
            app.suggestionresultTextArea = uitextarea(app.App_uifigure);
            app.suggestionresultTextArea.ValueChangedFcn = createCallbackFcn(app, @suggestbboxesButtonPushed, true);
            app.suggestionresultTextArea.Value = {'summary of results for *suggest box* are shown here.'};
            app.NovideofoundLabel = uilabel(app.App_uifigure);
            app.NovideofoundLabel.Text = 'No video found.';
            app.deleteallbboxesinframeButton = uibutton(app.App_uifigure, 'push');
            app.deleteallbboxesinframeButton.ButtonPushedFcn = createCallbackFcn(app, @deleteallbboxesinframeButtonPushed, true);
            app.deleteallbboxesinframeButton.Text = {'delete all bboxes '; 'in frame'};
            app.deleteallbboxesinvideoButton = uibutton(app.App_uifigure, 'push');
            app.deleteallbboxesinvideoButton.ButtonPushedFcn = createCallbackFcn(app, @deleteallbboxesinvideoButtonPushed, true);
            app.deleteallbboxesinvideoButton.Text = {'delete all bboxes '; 'in video'};
            app.COPYALLBBOXESPanel = uipanel(app.App_uifigure);
            app.COPYALLBBOXESPanel.Title = 'COPY ALL BBOXES';
            app.arrow_right_add_bboxes = uibutton(app.COPYALLBBOXESPanel, 'push');
            app.arrow_right_add_bboxes.ButtonPushedFcn = createCallbackFcn(app, @arrow_right_add_bboxesButtonPushed, true);
            app.arrow_right_add_bboxes.Text = '>';
            app.arrow_left_add_bboxes = uibutton(app.COPYALLBBOXESPanel, 'push');
            app.arrow_left_add_bboxes.ButtonPushedFcn = createCallbackFcn(app, @arrow_left_add_bboxesButtonPushed, true);
            app.arrow_left_add_bboxes.Text = '<';
            % Show the figure after all components are created
            app.App_uifigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_holo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.App_uifigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.App_uifigure)
        end
    end
end